classdef Tool_Wear_Monitoring_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ToolWearMonitoringSystemUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        DepthofCutmmEditField          matlab.ui.control.NumericEditField
        DepthofCutmmEditFieldLabel     matlab.ui.control.Label
        SelectTypeDropDownLabel        matlab.ui.control.Label
        ToolWearMonitoringSystemLabel  matlab.ui.control.Label
        LengthmmEditField              matlab.ui.control.NumericEditField
        SpeedmminEditField             matlab.ui.control.NumericEditField
        FeedRatemmtoothEditField       matlab.ui.control.NumericEditField
        FolderSelectionLabel           matlab.ui.control.Label
        SelectTypeDropDown             matlab.ui.control.DropDown
        InputFolderEditField           matlab.ui.control.EditField
        InputFolderEditFieldLabel      matlab.ui.control.Label
        FeedRatemmtoothEditFieldLabel  matlab.ui.control.Label
        SpeedmminEditFieldLabel        matlab.ui.control.Label
        LengthmmEditFieldLabel         matlab.ui.control.Label
        DetectAutomaticallyfromFolderButton  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        DetectIndividualImageButton    matlab.ui.control.Button
        RULLabel                       matlab.ui.control.Label
        RULEditField                   matlab.ui.control.NumericEditField
        VB_AverageLabel                matlab.ui.control.Label
        VB_AverageEditField            matlab.ui.control.NumericEditField
        VB_MaxLabel                    matlab.ui.control.Label
        VB_MaxEditField                matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        ToolWearDetection              matlab.ui.container.Menu
        TrainingCodeYOLOv3Menu         matlab.ui.container.Menu
        BinaryClassification           matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        CloseMenu                      matlab.ui.container.Menu
        UploadMenu                     matlab.ui.container.Menu
        IndividualImageMenu            matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
    end
    
    properties (Access = private)
        individualimage = '';% Description
        individualimageFileName = '';
        imagefolder = '';
        cameradestination = '';

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ToolWearMonitoringSystemUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {620, 620};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {321, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: DetectIndividualImageButton
        function DetectIndividualImageButtonPushed(app, event)
        
            % Obtain variables
            length = app.LengthmmEditField.Value;
            speed = app.SpeedmminEditField.Value;
            feed = app.FeedRatemmtoothEditField.Value;

            % Detection
                load('mainnet(0.001_0.0005_0.45)rev.mat');
                close(1);
                bottomposition = [0 800];
                Image_Rot = imrotate(app.individualimage,180);
                                
                     %CLAHE
                     LAB = rgb2lab(Image_Rot+30);
                     L = LAB(:,:,1)/100;
                     L = adapthisteq(L,'NumTiles',[8 8],'ClipLimit',0.005);
                     LAB(:,:,1) = L*100;
                     Image_CLAHE = lab2rgb(LAB);
                                
                     %enlarging
                     RegionInterest = [0 0 900 900];   % please fine tune the size of this rect to get the best result [0 0 900 900]
                     Image_Enlarge = imcrop(Image_CLAHE,RegionInterest);
                
                     %Resizing
                     imdsReSz=im2single(imresize(Image_Enlarge,networkInputSize(1:2)));
                     %I = imdsReSz;
                     I = Image_Enlarge;
                           
                           
                     %% call the Flank_Wear_Prediction function to deplay VB_max, VB_average and RUL
                                
                     %Predicted_values = Flank_Wear_Prediction(Image_Enlarge,Length,speed,Feed_rate);
                                  
                     %% Yolov3 detector to display image and B-Box
                               
                     [bboxes,scores,labels] = detect(yolov3Detector,I);
                
                     % filter based on confidence
                     conffilter = 0.2;
                     scoresbelow = find(abs(scores)<conffilter);
                     scoresupper = find(abs(scores)>conffilter);
                     scores(scoresbelow) = 0;
                     scoresfiltered = scores(scores~=0);
                     bboxesfiltered = bboxes(scoresupper,:);
                     labelsfiltered = labels(scoresupper,:);
                
                     % Display the detections on image.
                     Final_Image = insertText(I,bottomposition,app.individualimageFileName,'BoxColor','black','FontSize',50,'TextColor','white');
                     
                     if ~isempty(scoresfiltered)
                                   
                            Final_Image = insertObjectAnnotation(Final_Image,'rectangle',bboxesfiltered,labelsfiltered,'LineWidth',5,'TextBoxOpacity',0.8,'FontSize',45);
                                        
                     end 
                imshow(Final_Image,'parent',app.UIAxes)
                clearvars  individualimage individualimageFileName          


        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
             closereq;
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % change the folder path
            storingFolder = 'C:\Users\haowe\Desktop\UNM\FYP\image_folder\Results';
            n=numel(dir(fullfile(storingFolder, '*.jpg')));
            fileName=[storingFolder,'\Wear_Result_',datestr(now,'yymmdd'),'_',sprintf('%03d ',n+1),'.jpg'];
            
            exportgraphics(app.UIAxes,fileName);
            
            winopen(fileName);
        end

        % Callback function
        function TrainedNetworkYOLOv3MenuSelected(app, event)
            f=uifigure;
            d = uiprogressdlg(f,'Title','Please Wait',...
                'Message','Loading Trained Network');
            pause(.5)
            
            d.Value = .33; 
            d.Message = 'Loading Trained Network';
            pause(1)
            load("TrainedNetwork10.mat");
                       
            d.Value = .67;
            d.Message = 'Assigning Data';
            pause(1)
            assignin('base','net',net);
            assignin('base','networkOutputs',networkOutputs);
            assignin('base','anchorBoxes',anchorBoxes);
            assignin('base','anchorBoxMasks',anchorBoxMasks);
            assignin('base','classNames',classNames);
           
            d.Value = 1;
            d.Message = 'Finishing';
            pause(1)
            
            close(f)
        end

        % Callback function
        function TrainedNetworkSVMMenuSelected(app, event)
            f=uifigure;
            d = uiprogressdlg(f,'Title','Please Wait',...
                'Message','Loading Trained Network');
            pause(.5)
            
            d.Value = .33; 
            d.Message = 'Loading Trained Network';
            pause(1)
            load('tool_wear_prediction.mat');
            
            d.Value = .67;
            d.Message = 'Assigning Data';
            pause(1)
            assignin('base','imageSize',imageSize);
            assignin('base','net1',net1);
            assignin('base','featureLayer',featureLayer);
            assignin('base','classifier',classifier);
            
            d.Value = 1;
            d.Message = 'Finishing';
            pause(1)
            
            close(f)
        end

        % Menu selected function: IndividualImageMenu
        function IndividualImageMenuSelected(app, event)
            % Get the name of the file that the user wants to use.
            
            [baseFileName, folder] = uigetfile('.tif', 'Select a file');
            app.individualimageFileName = baseFileName;
            if baseFileName == 0
              % User clicked the Cancel button.indicidualimageFileNameindicidualimageFileNameindividualimageFileName
              return;
            end

            individualimagedir = fullfile(folder, baseFileName);
            app.individualimage = imread(individualimagedir);
            imshow(app.individualimage,'parent',app.UIAxes); 


        end

        % Menu selected function: ToolWearDetection
        function ToolWearDetectionSelected(app, event)
            open('tool_wear_prediction.m');
        end

        % Menu selected function: TrainingCodeYOLOv3Menu
        function TrainingCodeYOLOv3MenuSelected(app, event)
            open('yolov3training.m');
        end

        % Menu selected function: BinaryClassification
        function BinaryClassificationSelected(app, event)
            open('binaryclassifier.m');
        end

        % Button pushed function: DetectAutomaticallyfromFolderButton
        function DetectAutomaticallyfromFolderButtonPushed(app, event)
            
                % Obtain variables
                length = app.LengthmmEditField.Value;
                speed = app.SpeedmminEditField.Value;
                feed = app.FeedRatemmtoothEditField.Value;
                depthofcut = app.DepthofCutmmEditField.Value;
                
                
                %obtain the newest folder from the imagefolder
                %cameradestination = ('C:\Users\user\Pictures\Baumer Image Records\VCXU-65M.R');
                %run('obtainnewestfolder.m');
                %specify the destination of output
                outputdestination = pwd;
                %Specify the folder of the images
                %image_folder = 'C:\Users\Kevin\OneDrive - University of Nottingham Malaysia\Documents\Study\FYP\final\3_03_2022 folder\VCXU-65M.R_700006003432_220310-162919'; 
                image_folder = app.imagefolder;
                run('binaryclassifier.m');
                addpath(mainfolder,wearfolder2,wearfiles2);
                clearvars -except mainfolder wearfolder2 wearfiles2 length speed feed;
                wearfolderdir = wearfiles2;
                %loads the trained network
                warning('off','all');
                load('mainnet(0.001_0.0005_0.45)rev.mat');
                close(1);
                %This function calls all functions to execute the classification and
                % prediction of tool wear and remaining useful life (RUL). The variables
                % are Length, speed, and Feed rate.
                %% Loading folder from online data acquisition
                % change to the folderpath that store the images
                imageStorageFolder = wearfiles2;
                % get the all the jpg images in the folder 
                imageFolder = dir(imageStorageFolder);
                % identifying the number of files in the folder 
                nfiles = numel(imageFolder);
                % the newest folder will be the last folder
                currentFolderName = imageFolder(nfiles).name;
                currentFolder = imageFolder(nfiles).folder;
                currentFolderPath = fullfile(currentFolder,currentFolderName);


                % access the lastest folder and read the total number of images inside
                ImageList = dir(fullfile(currentFolder, '*.tif'));
                total_images = numel(ImageList);


                % resize the image to [227 227 3] for conv1;
                inputSize = baseNetwork.Layers(1).InputSize;
                imds_our_images = imageDatastore(imageStorageFolder);
                
                featureLayer = 'conv1';
                
                
                Image_Montage = cell(total_images,1);
                bottomposition = [0 800];
                %%
                for i = 1:total_images
                     current_imagename = ImageList(i).name;
                     current_image = imread(current_imagename);
                     
                     if ndims(current_image) == 2  %#ok<ISMAT> 
                    % It's a 2D image.  Convert to 3-D.
                        current_image = cat(3, current_image, current_image, current_image);
                     end
                
                     Image_Rot = imrotate(current_image,180);
                                
                     %CLAHE
                     LAB = rgb2lab(Image_Rot+30);
                     L = LAB(:,:,1)/100;
                     L = adapthisteq(L,'NumTiles',[8 8],'ClipLimit',0.005);
                     LAB(:,:,1) = L*100;
                     Image_CLAHE = lab2rgb(LAB);
                                
                     %enlarging
                     RegionInterest = [0 0 900 900];   % please fine tune the size of this rect to get the best result [0 0 900 900]
                     Image_Enlarge = imcrop(Image_CLAHE,RegionInterest);
                
                     %Resizing
                     imdsReSz=im2single(imresize(Image_Enlarge,networkInputSize(1:2)));
                     %I = imdsReSz;
                     I = Image_Enlarge;
                           
                           
                     %% call the Flank_Wear_Prediction function to deplay VB_max, VB_average and RUL
                                
                     %Predicted_values = Flank_Wear_Prediction(Image_Enlarge,Length,speed,Feed_rate);
                                  
                     %% Yolov3 detector to display image and B-Box
                               
                     [bboxes,scores,labels] = detect(yolov3Detector,I);
                
                     % filter based on confidence
                     conffilter = 0.2;
                     scoresbelow = find(abs(scores)<conffilter);
                     scoresupper = find(abs(scores)>conffilter);
                     scores(scoresbelow) = 0;
                     scoresfiltered = scores(scores~=0);
                     bboxesfiltered = bboxes(scoresupper,:);
                     labelsfiltered = labels(scoresupper,:);
                
                     % Display the detections on image.
                     Final_Image = insertText(I,bottomposition,current_imagename,'BoxColor','black','FontSize',50,'TextColor','white');
                     
                     if ~isempty(scoresfiltered)
                                   
                            Final_Image = insertObjectAnnotation(Final_Image,'rectangle',bboxesfiltered,labelsfiltered,'LineWidth',5,'TextBoxOpacity',0.8,'FontSize',45);
                                        
                     end 
                
                     Image_Montage{i} = Final_Image;
                       
                          
                end
                
                run("showfigure.m");
                save(append(wearfolderdir, '\Image Montage.mat'),"Image_Montage");
                saveas(gca, fullfile(wearfolderdir, 'wearmontage'), 'jpeg');    
        end

        % Value changed function: InputFolderEditField
        function InputFolderEditFieldValueChanged(app, event)
            switch app.SelectTypeDropDown.Value
                case 'Camera Destination'
                app.cameradestination = app.InputFolderEditField.Value;
                cameradestination = app.cameradestination;
                run("obtainnewestfolder.m")
                app.imagefolder = image_folder;

                case 'Specific Folder'
                image_folder = app.InputFolderEditField.Value;
                app.imagefolder = image_folder;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ToolWearMonitoringSystemUIFigure = uifigure('Visible', 'off');
            app.ToolWearMonitoringSystemUIFigure.Name = 'Tool Wear Monitoring System';
            app.ToolWearMonitoringSystemUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ToolWearMonitoringSystemUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DetectAutomaticallyfromFolderButton = uibutton(app.LeftPanel, 'push');
            app.DetectAutomaticallyfromFolderButton.ButtonPushedFcn = createCallbackFcn(app, @DetectAutomaticallyfromFolderButtonPushed, true);
            app.DetectAutomaticallyfromFolderButton.Text = 'Detect Automatically from Folder';
            app.LengthmmEditFieldLabel = uilabel(app.LeftPanel);
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.SpeedmminEditFieldLabel = uilabel(app.LeftPanel);
            app.SpeedmminEditFieldLabel.Text = 'Speed m/min';
            app.FeedRatemmtoothEditFieldLabel = uilabel(app.LeftPanel);
            app.FeedRatemmtoothEditFieldLabel.Text = 'Feed Rate (mm/tooth)';
            app.InputFolderEditFieldLabel = uilabel(app.LeftPanel);
            app.InputFolderEditFieldLabel.Text = 'Input Folder';
            app.InputFolderEditField = uieditfield(app.LeftPanel, 'text');
            app.InputFolderEditField.ValueChangedFcn = createCallbackFcn(app, @InputFolderEditFieldValueChanged, true);
            app.InputFolderEditField.Value = 'C:\Users\user\Pictures\Baumer Image Records\VCXU-65M.R';
            app.SelectTypeDropDown = uidropdown(app.LeftPanel);
            app.SelectTypeDropDown.Items = {'Camera Destination', 'Specific Folder', ''};
            app.SelectTypeDropDown.Value = 'Camera Destination';
            app.FolderSelectionLabel = uilabel(app.LeftPanel);
            app.FolderSelectionLabel.Text = 'Folder Selection';
            app.FeedRatemmtoothEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FeedRatemmtoothEditField.Limits = [0.001 40];
            app.FeedRatemmtoothEditField.ValueDisplayFormat = '%.3f';
            app.FeedRatemmtoothEditField.Value = 0.08;
            app.SpeedmminEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SpeedmminEditField.Limits = [0.001 100];
            app.SpeedmminEditField.Value = 40;
            app.LengthmmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LengthmmEditField.Limits = [0 10000000];
            app.LengthmmEditField.ValueDisplayFormat = '%8.f';
            app.LengthmmEditField.Value = 4000;
            app.ToolWearMonitoringSystemLabel = uilabel(app.LeftPanel);
            app.ToolWearMonitoringSystemLabel.Text = 'Tool Wear Monitoring System';
            app.SelectTypeDropDownLabel = uilabel(app.LeftPanel);
            app.SelectTypeDropDownLabel.Text = 'Select Type';
            app.DepthofCutmmEditFieldLabel = uilabel(app.LeftPanel);
            app.DepthofCutmmEditFieldLabel.Text = 'Depth of Cut (mm)';
            app.DepthofCutmmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DepthofCutmmEditField.Limits = [0 10000000];
            app.DepthofCutmmEditField.ValueDisplayFormat = '%8.f';
            app.DepthofCutmmEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.VB_MaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.VB_MaxEditField.ValueDisplayFormat = '%7.2f';
            app.VB_MaxEditField.Editable = 'off';
            app.VB_MaxLabel = uilabel(app.RightPanel);
            app.VB_MaxLabel.Text = 'VB_Max';
            app.VB_AverageEditField = uieditfield(app.RightPanel, 'numeric');
            app.VB_AverageEditField.ValueDisplayFormat = '%7.2f';
            app.VB_AverageEditField.Editable = 'off';
            app.VB_AverageLabel = uilabel(app.RightPanel);
            app.VB_AverageLabel.Text = 'VB_Average';
            app.RULEditField = uieditfield(app.RightPanel, 'numeric');
            app.RULEditField.ValueDisplayFormat = '%7.2f';
            app.RULEditField.Editable = 'off';
            app.RULLabel = uilabel(app.RightPanel);
            app.RULLabel.Text = 'RUL';
            app.DetectIndividualImageButton = uibutton(app.RightPanel, 'push');
            app.DetectIndividualImageButton.ButtonPushedFcn = createCallbackFcn(app, @DetectIndividualImageButtonPushed, true);
            app.DetectIndividualImageButton.Text = 'Detect Individual Image';
            app.FileMenu = uimenu(app.ToolWearMonitoringSystemUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.ToolWearDetection = uimenu(app.OpenMenu);
            app.ToolWearDetection.MenuSelectedFcn = createCallbackFcn(app, @ToolWearDetectionSelected, true);
            app.ToolWearDetection.Text = 'YOLOv3 Detection Code';
            app.TrainingCodeYOLOv3Menu = uimenu(app.OpenMenu);
            app.TrainingCodeYOLOv3Menu.MenuSelectedFcn = createCallbackFcn(app, @TrainingCodeYOLOv3MenuSelected, true);
            app.TrainingCodeYOLOv3Menu.Text = 'Training Code (YOLOv3)';
            app.BinaryClassification = uimenu(app.OpenMenu);
            app.BinaryClassification.MenuSelectedFcn = createCallbackFcn(app, @BinaryClassificationSelected, true);
            app.BinaryClassification.Text = 'Binary Classifier Code';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.UploadMenu = uimenu(app.ToolWearMonitoringSystemUIFigure);
            app.UploadMenu.Text = 'Upload';
            app.IndividualImageMenu = uimenu(app.UploadMenu);
            app.IndividualImageMenu.MenuSelectedFcn = createCallbackFcn(app, @IndividualImageMenuSelected, true);
            app.IndividualImageMenu.Text = 'Individual Image';
            % Show the figure after all components are created
            app.ToolWearMonitoringSystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tool_Wear_Monitoring_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ToolWearMonitoringSystemUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ToolWearMonitoringSystemUIFigure)
        end
    end
end